classdef OnePanelFig < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        BoldAxesCheckBox          matlab.ui.control.CheckBox
        RulerHeader               matlab.ui.control.Label
        YAxisSciNotationCheckBox  matlab.ui.control.CheckBox
        XAxisSciNotationCheckBox  matlab.ui.control.CheckBox
        XScaleDropDown            matlab.ui.control.DropDown
        XScaleDropDownLabel       matlab.ui.control.Label
        DelimiterLabel            matlab.ui.control.Label
        ScaleHeader               matlab.ui.control.Label
        AxesHeader                matlab.ui.control.Label
        LegendHeader              matlab.ui.control.Label
        LegendEditField           matlab.ui.control.EditField
        LegendEditFieldLabel      matlab.ui.control.Label
        YAxisEditField            matlab.ui.control.EditField
        YAxisEditFieldLabel       matlab.ui.control.Label
        XAxisEditField            matlab.ui.control.EditField
        XAxisEditFieldLabel       matlab.ui.control.Label
        YScaleDropDown            matlab.ui.control.DropDown
        YScaleDropDownLabel       matlab.ui.control.Label
        CenterPanel               matlab.ui.container.Panel
        UpdateButton              matlab.ui.control.Button
        FiguresDropDown           matlab.ui.control.DropDown
        FiguresDropDownLabel      matlab.ui.control.Label
        FiguresHeader             matlab.ui.control.Label
        LineWidthEditField        matlab.ui.control.NumericEditField
        LineWidthEditFieldLabel   matlab.ui.control.Label
        MarkerTypeDropDown        matlab.ui.control.DropDown
        MarkerTypeDropDownLabel   matlab.ui.control.Label
        LineStyleDropDown         matlab.ui.control.DropDown
        LineStyleDropDownLabel    matlab.ui.control.Label
        StyleHeader               matlab.ui.control.Label
        MarkerSizeEditField       matlab.ui.control.NumericEditField
        MarkerSizeEditFieldLabel  matlab.ui.control.Label
        ColoursHeader             matlab.ui.control.Label
        ColoursDropDown           matlab.ui.control.DropDown
        ColoursDropDownLabel      matlab.ui.control.Label
        RightPanel                matlab.ui.container.Panel
        SpanButton                matlab.ui.control.Button
        SaveButton                matlab.ui.control.Button
        LoadButton                matlab.ui.control.Button
        ResetButton               matlab.ui.control.Button
        UpperXEditField           matlab.ui.control.NumericEditField
        UpperXEditFieldLabel      matlab.ui.control.Label
        UpperYEditField           matlab.ui.control.NumericEditField
        UpperYEditFieldLabel      matlab.ui.control.Label
        LowerYEditField           matlab.ui.control.NumericEditField
        LowerYEditFieldLabel      matlab.ui.control.Label
        LowerXEditField           matlab.ui.control.NumericEditField
        LowerXEditFieldLabel      matlab.ui.control.Label
        LimitsHeader              matlab.ui.control.Label
        PresetsHeader             matlab.ui.control.Label
        FigPresetDropDown         matlab.ui.control.DropDown
        FigPresetDropDownLabel    matlab.ui.control.Label
        InvertX                   matlab.ui.control.ToggleSwitch
        InvertXLabel              matlab.ui.control.Label
        InvertY                   matlab.ui.control.ToggleSwitch
        InvertYLabel              matlab.ui.control.Label
        InvertHeader              matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    properties (Access = private)
        fig % gcf global variable
        ax % gca global variable
        legends % Global variable for legend label
        ColorPallet % Global variable to be changed based on colour selection
        NumberOfLines % Property to hold size of number of lines
        NumberOfDataSets % Property to hold size of different data
        initialX % Property to hold the initial X limits
        initialY % Property to hold the initial Y limits
        LineStyle % Property to hold the characters of associated linestyle
        Marker % Global variable to hold which marker is being used
        oldMarker % Global variable to hold non-connecting marker
        connecting % An overriding property to undo GUI styling
        Size % Global variable to hold marker size
        Width % Global variable to hold line width
        initialOuter % Global variable to hold default position
        initialInner % Global variable to hold default position
        figHandles % Global variable to hold all figure handles

        % Property to hold all header labels for preset
        headersToSave = {'ScaleHeader', 'AxesHeader', 'LegendHeader', 'DelimiterLabel', 'RulerHeader', 'FiguresHeader', 'StyleHeader', 'ColoursHeader', 'InvertHeader', 'LimitsHeader', 'PresetsHeader'}

        % Property to hold all associated labels for preset
        labelsToSave = {'YScaleDropDownLabel', 'XScaleDropDownLabel', 'YAxisEditFieldLabel', 'XAxisEditFieldLabel', 'LegendEditFieldLabel', ...
            'FiguresDropDownLabel', 'LineStyleDropDownLabel', 'LineWidthEditFieldLabel', 'MarkerTypeDropDownLabel', 'MarkerSizeEditFieldLabel','ColoursDropDownLabel', ...
            'InvertYLabel', 'InvertXLabel', 'LowerXEditFieldLabel', 'UpperXEditFieldLabel','LowerYEditFieldLabel', 'UpperYEditFieldLabel','FigPresetDropDownLabel'}

        % Property to hold all values for preset
        valuesToSave = {'YScaleDropDown', 'XScaleDropDown', 'YAxisEditField', 'XAxisEditField', 'BoldAxesCheckBox', 'LegendEditField', 'YAxisSciNotationCheckBox', 'XAxisSciNotationCheckBox', ...
            'FiguresDropDown', 'LineStyleDropDown', 'LineWidthEditField', 'MarkerTypeDropDown', 'MarkerSizeEditField', 'ColoursDropDown', ...
            'InvertY', 'InvertX', 'LowerXEditField', 'UpperXEditField','LowerYEditField', 'UpperYEditField', 'FigPresetDropDown'}
    end

    methods (Access = private)
        % Functions to choose/set colours
        function colors = distinguishable_colors(~,n_colors,bg,func)
            % DISTINGUISHABLE_COLORS: pick colors that are maximally perceptually distinct
            %
            % When plotting a set of lines, you may want to distinguish them by color.
            % By default, Matlab chooses a small set of colors and cycles among them,
            % and so if you have more than a few lines there will be confusion about
            % which line is which. To fix this problem, one would want to be able to
            % pick a much larger set of distinct colors, where the number of colors
            % equals or exceeds the number of lines you want to plot. Because our
            % ability to distinguish among colors has limits, one should choose these
            % colors to be "maximally perceptually distinguishable."
            %
            % This function generates a set of colors which are distinguishable
            % by reference to the "Lab" color space, which more closely matches
            % human color perception than RGB. Given an initial large list of possible
            % colors, it iteratively chooses the entry in the list that is farthest (in
            % Lab space) from all previously-chosen entries. While this "greedy"
            % algorithm does not yield a global maximum, it is simple and efficient.
            % Moreover, the sequence of colors is consistent no matter how many you
            % request, which facilitates the users' ability to learn the color order
            % and avoids major changes in the appearance of plots when adding or
            % removing lines.
            %
            % Syntax:
            %   colors = distinguishable_colors(n_colors)
            % Specify the number of colors you want as a scalar, n_colors. This will
            % generate an n_colors-by-3 matrix, each row representing an RGB
            % color triple. If you don't precisely know how many you will need in
            % advance, there is no harm (other than execution time) in specifying
            % slightly more than you think you will need.
            %
            %   colors = distinguishable_colors(n_colors,bg)
            % This syntax allows you to specify the background color, to make sure that
            % your colors are also distinguishable from the background. Default value
            % is white. bg may be specified as an RGB triple or as one of the standard
            % "ColorSpec" strings. You can even specify multiple colors:
            %     bg = {'w','k'}
            % or
            %     bg = [1 1 1; 0 0 0]
            % will only produce colors that are distinguishable from both white and
            % black.
            %
            %   colors = distinguishable_colors(n_colors,bg,rgb2labfunc)
            % By default, distinguishable_colors uses the image processing toolbox's
            % color conversion functions makecform and applycform. Alternatively, you
            % can supply your own color conversion function.
            %
            % Example:
            %   c = distinguishable_colors(25);
            %   figure
            %   image(reshape(c,[1 size(c)]))
            %
            % Example using the file exchange's 'colorspace':
            %   func = @(x) colorspace('RGB->Lab',x);
            %   c = distinguishable_colors(25,'w',func);

            % Copyright 2010-2011 by Timothy E. Holy

            % Parse the inputs
            if (nargin < 3)
                bg = [1 1 1];  % default white background
            else
                if iscell(bg)
                    % User specified a list of colors as a cell aray
                    bgc = bg;
                    for i = 1:length(bgc)
                        bgc{i} = parsecolor(app,bgc{i});
                    end
                    bg = cat(1,bgc{:});
                else
                    % User specified a numeric array of colors (n-by-3)
                    bg = parsecolor(app, bg);
                end
            end

            % Generate a sizable number of RGB triples. This represents our space of
            % possible choices. By starting in RGB space, we ensure that all of the
            % colors can be generated by the monitor.
            n_grid = 30;  % number of grid divisions along each axis in RGB space
            x = linspace(0,1,n_grid);
            [R,G,B] = ndgrid(x,x,x);
            rgb = [R(:) G(:) B(:)];
            if (n_colors > size(rgb,1)/3)
                error('You can''t readily distinguish that many colors');
            end

            % Convert to Lab color space, which more closely represents human
            % perception
            if (nargin > 3)
                lab = func(rgb);
                bglab = func(bg);
            else
                C = makecform('srgb2lab');
                lab = applycform(rgb,C);
                bglab = applycform(bg,C);
            end

            % If the user specified multiple background colors, compute distances
            % from the candidate colors to the background colors
            mindist2 = inf(size(rgb,1),1);
            for i = 1:size(bglab,1)-1
                dX = bsxfun(@minus,lab,bglab(i,:)); % displacement all colors from bg
                dist2 = sum(dX.^2,2);  % square distance
                mindist2 = min(dist2,mindist2);  % dist2 to closest previously-chosen color
            end

            % Iteratively pick the color that maximizes the distance to the nearest
            % already-picked color
            colors = zeros(n_colors,3);
            lastlab = bglab(end,:);   % initialize by making the "previous" color equal to background
            for i = 1:n_colors
                dX = bsxfun(@minus,lab,lastlab); % displacement of last from all colors on list
                dist2 = sum(dX.^2,2);  % square distance
                mindist2 = min(dist2,mindist2);  % dist2 to closest previously-chosen color
                [~,index] = max(mindist2);  % find the entry farthest from all previously-chosen colors
                colors(i,:) = rgb(index,:);  % save for output
                lastlab = lab(index,:);  % prepare for next iteration
            end
        end

        function c = parsecolor(app,s)
            if ischar(s)
                c = colorstr2rgb(app,s);
            elseif isnumeric(s) && size(s,2) == 3
                c = s;
            else
                error('MATLAB:InvalidColorSpec','Color specification cannot be parsed.');
            end
        end

        function c = colorstr2rgb(~,c)
            % Convert a color string to an RGB value.
            % This is cribbed from Matlab's whitebg function.
            % Why don't they make this a stand-alone function?
            rgbspec = [1 0 0;0 1 0;0 0 1;1 1 1;0 1 1;1 0 1;1 1 0;0 0 0];
            cspec = 'rgbwcmyk';
            k = find(cspec==c(1));
            if isempty(k)
                error('MATLAB:InvalidColorString','Unknown color string.');
            end
            if k~=3 || length(c)==1
                c = rgbspec(k,:);
            elseif length(c)>2
                if strcmpi(c(1:3),'bla')
                    c = [0 0 0];
                elseif strcmpi(c(1:3),'blu')
                    c = [0 0 1];
                else
                    error('MATLAB:UnknownColorString', 'Unknown color string.');
                end
            end
        end

        % Function to set data properties 
        function setDataProp(app)
            %% Set data properties

            % Determine the type of plot (line or scatter)
            if isempty(findobj(app.fig,'Type','Line')) == 0
                % Alter GUI accordingly to each type
                app.LineWidthEditField.Editable = 1;
                app.LineWidthEditField.Enable = 1;
                app.LineWidthEditFieldLabel.Enable = 1;
                app.LineStyleDropDown.Editable = 1;
                app.LineStyleDropDown.Enable = 1;
                app.LineStyleDropDownLabel.Enable = 1;

%                 app.MarkerTypeDropDown.Editable = 0;
%                 app.MarkerTypeDropDown.Enable = 0;
%                 app.MarkerTypeDropDownLabel.Enable = 0;
%                 app.MarkerSizeEditField.Editable = 0;
%                 app.MarkerSizeEditField.Enable = 0;
%                 app.MarkerSizeEditFieldLabel.Enable = 0;

                % Then apply properties
                plotLineData = findobj(app.fig,'Type','Line');
                app.NumberOfLines = size(plotLineData,1);
                if isempty(app.ColorPallet)
                    app.ColorPallet = distinguishable_colors(app,app.NumberOfLines);
                end
                for count = 1:1:app.NumberOfLines
                    plotLineData(count).Marker = app.Marker;
                    plotLineData(count).MarkerSize = app.Size;
                    plotLineData(count).LineWidth = app.Width;
                    plotLineData(count).Color = app.ColorPallet(count,:);
                    legend(app.legends,'Location','best','fontsize',12) %Change legend entries in the curly brackets as needed
                end
                legend('boxoff');
            end

            % Determine the type of plot (line or scatter)
            if isempty(findobj(app.fig,'Type','Scatter')) == 0
                % Alter GUI accordingly to each type
                if app.connecting == 0
                    app.MarkerTypeDropDown.Editable = 1;
                    app.MarkerTypeDropDown.Enable = 1;
                    app.MarkerTypeDropDownLabel.Enable = 1;
                    app.MarkerSizeEditField.Editable = 1;
                    app.MarkerSizeEditField.Enable = 1;
                    app.MarkerSizeEditFieldLabel.Enable = 1;
    
                    app.LineWidthEditField.Editable = 0;
                    app.LineWidthEditField.Enable = 0;
                    app.LineWidthEditFieldLabel.Enable = 0;
                    app.LineStyleDropDown.Editable = 0;
                    app.LineStyleDropDown.Enable = 0;
                    app.LineStyleDropDownLabel.Enable = 0;
                end
                % Then apply properties
                plotScatterData = findobj(app.fig,'Type','Scatter');
                app.NumberOfDataSets = size(plotScatterData,1);
                if isempty(app.ColorPallet)
                    app.ColorPallet = distinguishable_colors(app,app.NumberOfDataSets);
                end
                for count = 1:1:app.NumberOfDataSets
                    plotScatterData(count).Marker = app.Marker;
                    plotScatterData(count).SizeData = app.Size;
                    plotScatterData(count).MarkerEdgeColor = app.ColorPallet(count,:);
                    plotScatterData(count).MarkerFaceColor = plotScatterData(count).MarkerEdgeColor;
                    legend(app.legends,'Location','best','fontsize',12) %Change legend entries in the curly brackets as needed
                end
                legend('boxoff');
            end
        end
        
        % Callback function to handle close request/preset save
        function dlgcallback(app,~,event)
            if event.SelectedOption == "Yes"
                SaveButtonPushed(app);
                delete(app);
            else
                delete(app);
            end
        end
        
        % Function to reapply the GUI values to selected figure
        function repopulate(app)
            YScaleDropDownValueChanged(app);
            XScaleDropDownValueChanged(app);
            YAxisEditFieldValueChanged(app);
            XAxisEditFieldValueChanged(app);
            BoldAxesCheckBoxValueChanged(app);
            LegendEditFieldValueChanged(app);
            YAxisSciNotationCheckBoxValueChanged(app);
            XAxisSciNotationCheckBoxValueChanged(app);
            ColoursDropDownValueChanged(app);
            LineStyleDropDownValueChanged(app);
            LineWidthEditFieldValueChanged(app);
            MarkerTypeDropDownValueChanged(app);
            MarkerSizeEditFieldValueChanged(app);
            InvertYValueChanged(app);
            InvertXValueChanged(app);
            LowerXEditFieldValueChanged(app);
            UpperXEditFieldValueChanged(app);
            LowerYEditFieldValueChanged(app);
            UpperYEditFieldValueChanged(app);
            FigPresetDropDownValueChanged(app); % This was messing with populated figure dropdown
        end
        
        
        function findFigures(app)
            % Find all figures
            app.figHandles = get(groot,'Children');
            sz = size(app.figHandles,1);
            items = strings(sz,1);

            for i=1:sz
                items(i) = strcat("Figure ", num2str(app.figHandles(i).Number));
            end

            app.FiguresDropDown.Items = items;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            findFigures(app);

            % General Setup
            app.fig = gcf;
            app.ax = gca;
           
            app.fig.Color = 'white';
            app.fig.WindowStyle = 'normal';
            app.fig.Units = 'centimeters';

            app.ax.Color = 'white';
            app.ax.LineWidth = 1.5;
            app.ax.XGrid = 'off';
            app.ax.YGrid = 'off';
            app.ax.Box = 'on';
            app.ax.FontName = 'Arial';
            %app.ax.FontWeight = 'bold';
            app.ax.Units = 'centimeters';

            % Initialize empty properties to default values
            app.Size = 40;
            app.Width = 2;
            if isempty(findobj(app.fig,'Type','Scatter')) == 0
                app.MarkerTypeDropDown.Value = 'Square';
                app.Marker = 'square';
            else
                app.Marker = 'none';
            end
            app.LineStyle = '--';
            app.connecting = 0;

            % Set initial axes properties          
            app.initialX = app.ax.XLim;
            app.initialY = app.ax.YLim;

            app.ax.XMinorTick = 'on';
            app.ax.YMinorTick = 'on';
            app.ax.TickLength = [0.03 0.01];

            setDataProp(app);

            app.ax.FontSize = 14;

            % Get rid of extra white space around figure
            app.fig.WindowStyle = 'normal';
            CurrentFigPos = app.fig.Position;

            % Default aspect ratio
            app.ax.OuterPosition = [0 0 CurrentFigPos(3) 8];
            app.fig.Position = [CurrentFigPos(1) CurrentFigPos(2) CurrentFigPos(3) 8]; %Width static, change height as needed. 8.5 x 7.5 cm is suitable for 1 plot.
            
            % Initialize default positions into properties 
            app.initialInner = [15.134167,11.56229167,14.8167,8.0];

            % Changing limits back since functions above skew them
            app.ax.XLim = app.initialX;
            app.ax.YLim = app.initialY;

            % GUI should reflect default values
            app.LowerXEditField.Value = app.ax.XLim(1);
            app.UpperXEditField.Value = app.ax.XLim(2);
            app.LowerYEditField.Value = app.ax.YLim(1);
            app.UpperYEditField.Value = app.ax.YLim(2);
            app.MarkerSizeEditField.Value = app.Size;
            app.LineWidthEditField.Value = app.Width;
        end

        % Value changed function: YScaleDropDown
        function YScaleDropDownValueChanged(app, event)
            value = app.YScaleDropDown.Value;
            app.ax.YScale = value;
        end

        % Value changed function: XAxisEditField
        function XAxisEditFieldValueChanged(app, event)
            value = app.XAxisEditField.Value;
            xlabel(app.ax,value);
        end

        % Value changed function: YAxisEditField
        function YAxisEditFieldValueChanged(app, event)
            value = app.YAxisEditField.Value;
            ylabel(app.ax,value);
        end

        % Value changed function: LegendEditField
        function LegendEditFieldValueChanged(app, event)
            value = app.LegendEditField.Value;
            app.legends = split(value, ";");

            setDataProp(app);
        end

        % Value changed function: InvertY
        function InvertYValueChanged(app, event)
            value = string(app.InvertY.Value);
            tf = strcmp(value,'On');
            if tf == 1
                set(app.ax, 'YDir', 'reverse');
            else
                set(app.ax, 'YDir', 'normal');
            end
        end

        % Value changed function: InvertX
        function InvertXValueChanged(app, event)
            value = string(app.InvertX.Value);
            tf = strcmp(value,'On');
            if tf == 1
                set(app.ax, 'XDir', 'reverse');
            else
                set(app.ax, 'XDir', 'normal');
            end
        end

        % Value changed function: FigPresetDropDown
        function FigPresetDropDownValueChanged(app, event)
            value = app.FigPresetDropDown.Value;
        
            % Keep fig stationary, only change aspect ratio
            CurrentFigPos = app.fig.Position;

            if value == "Default"
                app.ax.OuterPosition = [0 0 app.initialInner(3) 8]; %Change as needed.
                app.fig.Position = [CurrentFigPos(1) CurrentFigPos(2) app.initialInner(3) 8]; %Width static, change height as needed. 8.5 x 7.5 cm is suitable for 1 plot.
            elseif value == "2:1 (Horizontal)"
                app.ax.OuterPosition = [0 0 2*app.initialInner(3) app.initialInner(3)];
                app.fig.Position = [CurrentFigPos(1) CurrentFigPos(2) 2*app.initialInner(3) app.initialInner(3)];
            elseif value == "2:1 (Vertical)"
                app.ax.OuterPosition = [0 0 app.initialInner(3) 2*app.initialInner(3)]; 
                app.fig.Position = [CurrentFigPos(1) CurrentFigPos(2) app.initialInner(3) 2*app.initialInner(3)];
            elseif value == "Square"
                app.ax.OuterPosition = [0 0 app.initialInner(3) app.initialInner(3)]; 
                app.fig.Position = [CurrentFigPos(1) CurrentFigPos(2) app.initialInner(3) app.initialInner(3)];
            end
        end

        % Value changed function: XScaleDropDown
        function XScaleDropDownValueChanged(app, event)
            value = app.XScaleDropDown.Value;
            app.ax.XScale = value;
        end

        % Value changed function: XAxisSciNotationCheckBox
        function XAxisSciNotationCheckBoxValueChanged(app, event)
            value = app.XAxisSciNotationCheckBox.Value;
            if value == 1
                app.ax.XRuler.Exponent = 1; 
            else
                app.ax.XRuler.Exponent = 0;
            end
        end

        % Value changed function: YAxisSciNotationCheckBox
        function YAxisSciNotationCheckBoxValueChanged(app, event)
            value = app.YAxisSciNotationCheckBox.Value;
            if value == 1
                app.ax.YRuler.Exponent = 1; 
            else
                app.ax.YRuler.Exponent = 0;
            end
        end

        % Value changed function: ColoursDropDown
        function ColoursDropDownValueChanged(app, event)
            value = app.ColoursDropDown.Value;
            if value == "Multiple Data Sets"
                app.ColorPallet = distinguishable_colors(app,app.NumberOfDataSets);
            else
                app.ColorPallet = turbo(app.NumberOfLines);
%                 app.ColorPallet = distinguishable_colors(app,app.NumberOfLines);
            end
            setDataProp(app);
        end

        % Value changed function: MarkerSizeEditField
        function MarkerSizeEditFieldValueChanged(app, event)
            value = app.MarkerSizeEditField.Value;
            if value <= 0
                uialert(app.UIFigure,"Enter a value greater than zero","MarkerSzError","Icon","error")
            else
                app.Size = value;
            end
            setDataProp(app);
        end

        % Value changed function: LowerXEditField
        function LowerXEditFieldValueChanged(app, event)
            value = app.LowerXEditField.Value;
            app.ax.XLim(1) = value;
        end

        % Value changed function: LowerYEditField
        function LowerYEditFieldValueChanged(app, event)
            value = app.LowerYEditField.Value;
            app.ax.YLim(1) = value;
        end

        % Value changed function: BoldAxesCheckBox
        function BoldAxesCheckBoxValueChanged(app, event)
            value = app.BoldAxesCheckBox.Value;
            if value == 1
                app.ax.FontWeight = 'bold';
            else
                app.ax.FontWeight = 'normal';
            end
        end

        % Value changed function: UpperXEditField
        function UpperXEditFieldValueChanged(app, event)
            value = app.UpperXEditField.Value;
            app.ax.XLim(2) = value;
        end

        % Value changed function: UpperYEditField
        function UpperYEditFieldValueChanged(app, event)
            value = app.UpperYEditField.Value;
            app.ax.YLim(2) = value;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.ax.XLim = app.initialX;
            app.ax.YLim = app.initialY;

            app.LowerXEditField.Value = app.ax.XLim(1);
            app.UpperXEditField.Value = app.ax.XLim(2);
            app.LowerYEditField.Value = app.ax.YLim(1);
            app.UpperYEditField.Value = app.ax.YLim(2);
        end

        % Value changed function: LineStyleDropDown
        function LineStyleDropDownValueChanged(app, event)
            value = string(app.LineStyleDropDown.Value);
            
            if value == "Solid"
                app.ax.LineStyleOrder = '-';
            elseif value == "Dashed"
                app.ax.LineStyleOrder = '--';
            elseif value == "Dotted"
                app.ax.LineStyleOrder = ':';
            elseif value == "Dash-Dot"
                app.ax.LineStyleOrder = '-.';
            end

            app.LineStyle = app.ax.LineStyleOrder;
        end

        % Value changed function: MarkerTypeDropDown
        function MarkerTypeDropDownValueChanged(app, event)
            value = string(app.MarkerTypeDropDown.Value);

            if value == "None"
                app.Marker = 'none';
            elseif value == "Square"
                app.Marker = 'square';
            elseif value == "Circle"
                app.Marker = "o";
            elseif value == "Point"
                app.Marker = '.';
            elseif value == "Plus Sign"
                app.Marker = '+';
            elseif value == "Asterisk"
                app.Marker = '*';
            elseif value == "Cross"
                app.Marker = 'x';
            elseif value == "Horizontal Line"
                app.Marker = '_';
            elseif value == "Vertical Line"
                app.Marker = '|';
            elseif value == "Diamond"
                app.Marker = 'diamond';
            end

            setDataProp(app);
        end

        % Value changed function: LineWidthEditField
        function LineWidthEditFieldValueChanged(app, event)
            value = app.LineWidthEditField.Value;
            if value <= 0
                uialert(app.UIFigure,"Enter a value greater than zero","LineWidthError","Icon","error")
            else
                app.Width = value;
            end
            setDataProp(app);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            %% Callback function to handle loading of preset
            % Select the preset file
            [file, folder] = uigetfile('*.mat', "Select a preset");
            full = fullfile(folder, file);
            
            % Load file into struct
            state = load(full);
            state = state.state;
            
            % Load headers 
            for i = 1:length(app.headersToSave)
                app.(app.headersToSave{i}).Text = state.(app.headersToSave{i}).Text;
            end

            % Load labels
            for i = 1:length(app.labelsToSave)
                app.(app.labelsToSave{i}).Text = state.(app.labelsToSave{i}).Text;
            end
            
            % Load values
            for i = 1:length(app.valuesToSave)
                app.(app.valuesToSave{i}).Value = state.(app.valuesToSave{i}).Value;
            end

            % Now assign/update all preset values back to plot
            % Achieve this simply by calling every callback to utilize existing code
            repopulate(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            uiconfirm(app.UIFigure, "Do you want to save your current configuration to a preset?","Save Preset?", "Options", {'Yes', 'No'}, "CloseFcn", @(src,event)dlgcallback(app,src,event));
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            state = struct();

            % Save headers
            for i = 1:length(app.headersToSave)
                state.(app.headersToSave{i}).Text = app.(app.headersToSave{i}).Text;
            end

            % Save labels
            for i = 1:length(app.labelsToSave)
                state.(app.labelsToSave{i}).Text = app.(app.labelsToSave{i}).Text;
            end
            
            % Save values
            for i = 1:length(app.valuesToSave)
                state.(app.valuesToSave{i}).Value = app.(app.valuesToSave{i}).Value;
            end

            uisave('state');
        end

        % Value changed function: FiguresDropDown
        function FiguresDropDownValueChanged(app, event)
            value = app.FiguresDropDown.Value;

            % Extract handle number for identifier
            num = regexp(value, '\d*', 'Match');
            int = str2double(num);

            % Find the handle associated with the number
            try
                for i = 1:size(app.figHandles,1)
                    if int == app.figHandles(i).Number
                        currentHandle = app.figHandles(i);
                    end
                end
            catch 
                uialert(app.UIFigure,"Figure handle no longer exists, update and try again","Invalid/Deleted Figure");
                return
            end


            % Reset this to new gcf
            set(0, 'CurrentFigure', currentHandle);

            % Repopulate current figure to new handle
            app.fig = gcf;
            app.ax = gca;

            % Reset proper units
            app.fig.Units = 'centimeters';
            app.ax.Units = 'centimeters';

            repopulate(app);
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            findFigures(app);
        end

        % Button pushed function: SpanButton
        function SpanButtonPushed(app, event)
            % Set limits back to auto to find data and add some padding
            xlim('auto');
            xlim('padded');

            ylim('auto');
            ylim('padded');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {264, '1x', 257};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.YScaleDropDownLabel = uilabel(app.LeftPanel);
            app.YScaleDropDownLabel.Text = 'Y-Scale';
            app.YScaleDropDown = uidropdown(app.LeftPanel);
            app.YScaleDropDown.Items = {'Linear', 'Log'};
            app.YScaleDropDown.ValueChangedFcn = createCallbackFcn(app, @YScaleDropDownValueChanged, true);
            app.YScaleDropDown.Value = 'Linear';
            app.XAxisEditFieldLabel = uilabel(app.LeftPanel);
            app.XAxisEditFieldLabel.Text = 'X-Axis';
            app.XAxisEditField = uieditfield(app.LeftPanel, 'text');
            app.XAxisEditField.ValueChangedFcn = createCallbackFcn(app, @XAxisEditFieldValueChanged, true);
            app.YAxisEditFieldLabel = uilabel(app.LeftPanel);
            app.YAxisEditFieldLabel.Text = 'Y-Axis';
            app.YAxisEditField = uieditfield(app.LeftPanel, 'text');
            app.YAxisEditField.ValueChangedFcn = createCallbackFcn(app, @YAxisEditFieldValueChanged, true);
            app.LegendEditFieldLabel = uilabel(app.LeftPanel);
            app.LegendEditFieldLabel.Text = 'Legend';
            app.LegendEditField = uieditfield(app.LeftPanel, 'text');
            app.LegendEditField.ValueChangedFcn = createCallbackFcn(app, @LegendEditFieldValueChanged, true);
            app.LegendHeader = uilabel(app.LeftPanel);
            app.LegendHeader.Text = 'LEGEND LABELS ';
            app.AxesHeader = uilabel(app.LeftPanel);
            app.AxesHeader.Text = 'AXES LABELS';
            app.ScaleHeader = uilabel(app.LeftPanel);
            app.ScaleHeader.Text = 'SCALE TYPE';
            app.DelimiterLabel = uilabel(app.LeftPanel);
            app.DelimiterLabel.Text = '(Use ; as a delimiter)';
            app.XScaleDropDownLabel = uilabel(app.LeftPanel);
            app.XScaleDropDownLabel.Text = 'X-Scale';
            app.XScaleDropDown = uidropdown(app.LeftPanel);
            app.XScaleDropDown.Items = {'Linear', 'Log'};
            app.XScaleDropDown.ValueChangedFcn = createCallbackFcn(app, @XScaleDropDownValueChanged, true);
            app.XScaleDropDown.Value = 'Linear';
            app.XAxisSciNotationCheckBox = uicheckbox(app.LeftPanel);
            app.XAxisSciNotationCheckBox.ValueChangedFcn = createCallbackFcn(app, @XAxisSciNotationCheckBoxValueChanged, true);
            app.XAxisSciNotationCheckBox.Text = 'X-Axis Sci-Notation';
            app.YAxisSciNotationCheckBox = uicheckbox(app.LeftPanel);
            app.YAxisSciNotationCheckBox.ValueChangedFcn = createCallbackFcn(app, @YAxisSciNotationCheckBoxValueChanged, true);
            app.YAxisSciNotationCheckBox.Text = 'Y-Axis Sci-Notation';
            app.RulerHeader = uilabel(app.LeftPanel);
            app.RulerHeader.Text = 'RULER NOTATION';
            app.BoldAxesCheckBox = uicheckbox(app.LeftPanel);
            app.BoldAxesCheckBox.ValueChangedFcn = createCallbackFcn(app, @BoldAxesCheckBoxValueChanged, true);
            app.BoldAxesCheckBox.Text = 'Bold Axes';
            app.CenterPanel = uipanel(app.GridLayout);
            app.ColoursDropDownLabel = uilabel(app.CenterPanel);
            app.ColoursDropDownLabel.Text = 'Colours';
            app.ColoursDropDown = uidropdown(app.CenterPanel);
            app.ColoursDropDown.Items = {'Multiple Data Sets', 'Trends'};
            app.ColoursDropDown.ValueChangedFcn = createCallbackFcn(app, @ColoursDropDownValueChanged, true);
            app.ColoursDropDown.Value = 'Multiple Data Sets';
            app.ColoursHeader = uilabel(app.CenterPanel);
            app.ColoursHeader.Text = 'COLOURS';
            app.MarkerSizeEditFieldLabel = uilabel(app.CenterPanel);
            app.MarkerSizeEditFieldLabel.Text = 'MarkerSize';
            app.MarkerSizeEditField = uieditfield(app.CenterPanel, 'numeric');
            app.MarkerSizeEditField.ValueChangedFcn = createCallbackFcn(app, @MarkerSizeEditFieldValueChanged, true);
            app.StyleHeader = uilabel(app.CenterPanel);
            app.StyleHeader.Text = 'STYLE';
            app.LineStyleDropDownLabel = uilabel(app.CenterPanel);
            app.LineStyleDropDownLabel.Text = 'LineStyle';
            app.LineStyleDropDown = uidropdown(app.CenterPanel);
            app.LineStyleDropDown.Items = {'Solid', 'Dashed', 'Dotted', 'Dash-Dot'};
            app.LineStyleDropDown.ValueChangedFcn = createCallbackFcn(app, @LineStyleDropDownValueChanged, true);
            app.LineStyleDropDown.Value = 'Solid';
            app.MarkerTypeDropDownLabel = uilabel(app.CenterPanel);
            app.MarkerTypeDropDownLabel.Text = 'MarkerType';
            app.MarkerTypeDropDown = uidropdown(app.CenterPanel);
            app.MarkerTypeDropDown.Items = {'None', 'Square', 'Circle', 'Point', 'Plus Sign', 'Asterisk', 'Cross', 'Horizontal Line', 'Vertical Line', 'Diamond'};
            app.MarkerTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @MarkerTypeDropDownValueChanged, true);
            app.MarkerTypeDropDown.Value = 'None';
            app.LineWidthEditFieldLabel = uilabel(app.CenterPanel);
            app.LineWidthEditFieldLabel.Text = 'LineWidth';
            app.LineWidthEditField = uieditfield(app.CenterPanel, 'numeric');
            app.LineWidthEditField.ValueChangedFcn = createCallbackFcn(app, @LineWidthEditFieldValueChanged, true);
            app.FiguresHeader = uilabel(app.CenterPanel);
            app.FiguresHeader.Text = 'POPULATED FIGURES';
            app.FiguresDropDownLabel = uilabel(app.CenterPanel);
            app.FiguresDropDownLabel.Text = 'Figures';
            app.FiguresDropDown = uidropdown(app.CenterPanel);
            app.FiguresDropDown.Items = {};
            app.FiguresDropDown.ValueChangedFcn = createCallbackFcn(app, @FiguresDropDownValueChanged, true);
            app.FiguresDropDown.Value = {};
            app.UpdateButton = uibutton(app.CenterPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.RightPanel = uipanel(app.GridLayout);
            app.InvertHeader = uilabel(app.RightPanel);
            app.InvertHeader.Text = 'INVERT AXES';
            app.InvertYLabel = uilabel(app.RightPanel);
            app.InvertYLabel.Text = 'Y-Axis';
            app.InvertY = uiswitch(app.RightPanel, 'toggle');
            app.InvertY.ValueChangedFcn = createCallbackFcn(app, @InvertYValueChanged, true);
            app.InvertXLabel = uilabel(app.RightPanel);
            app.InvertXLabel.Text = 'X-Axis';
            app.InvertX = uiswitch(app.RightPanel, 'toggle');
            app.InvertX.ValueChangedFcn = createCallbackFcn(app, @InvertXValueChanged, true);
            app.FigPresetDropDownLabel = uilabel(app.RightPanel);
            app.FigPresetDropDownLabel.Text = 'Fig. Preset';
            app.FigPresetDropDown = uidropdown(app.RightPanel);
            app.FigPresetDropDown.Items = {'Default', '2:1 (Horizontal)', '2:1 (Vertical)', 'Square'};
            app.FigPresetDropDown.ValueChangedFcn = createCallbackFcn(app, @FigPresetDropDownValueChanged, true);
            app.FigPresetDropDown.Value = 'Default';
            app.PresetsHeader = uilabel(app.RightPanel);
            app.PresetsHeader.Text = 'PRESETS';
            app.LimitsHeader = uilabel(app.RightPanel);
            app.LimitsHeader.Text = 'LIMITS';
            app.LowerXEditFieldLabel = uilabel(app.RightPanel);
            app.LowerXEditFieldLabel.Text = 'Lower X';
            app.LowerXEditField = uieditfield(app.RightPanel, 'numeric');
            app.LowerXEditField.ValueChangedFcn = createCallbackFcn(app, @LowerXEditFieldValueChanged, true);
            app.LowerYEditFieldLabel = uilabel(app.RightPanel);
            app.LowerYEditFieldLabel.Text = 'Lower Y';
            app.LowerYEditField = uieditfield(app.RightPanel, 'numeric');
            app.LowerYEditField.ValueChangedFcn = createCallbackFcn(app, @LowerYEditFieldValueChanged, true);
            app.UpperYEditFieldLabel = uilabel(app.RightPanel);
            app.UpperYEditFieldLabel.Text = 'Upper Y';
            app.UpperYEditField = uieditfield(app.RightPanel, 'numeric');
            app.UpperYEditField.ValueChangedFcn = createCallbackFcn(app, @UpperYEditFieldValueChanged, true);
            app.UpperXEditFieldLabel = uilabel(app.RightPanel);
            app.UpperXEditFieldLabel.Text = 'Upper X';
            app.UpperXEditField = uieditfield(app.RightPanel, 'numeric');
            app.UpperXEditField.ValueChangedFcn = createCallbackFcn(app, @UpperXEditFieldValueChanged, true);
            app.ResetButton = uibutton(app.RightPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.LoadButton = uibutton(app.RightPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.RightPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SpanButton = uibutton(app.RightPanel, 'push');
            app.SpanButton.ButtonPushedFcn = createCallbackFcn(app, @SpanButtonPushed, true);
            app.SpanButton.Text = 'Span';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OnePanelFig

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end